classdef windApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DateEditFieldLabel          matlab.ui.control.Label
        DateEditField               matlab.ui.control.EditField
        RUNButton                   matlab.ui.control.Button
        map                         matlab.ui.container.Panel
        TimeSpinnerLabel            matlab.ui.control.Label
        TimeSpinner                 matlab.ui.control.Spinner
        AMPMButtonGroup             matlab.ui.container.ButtonGroup
        AMButton                    matlab.ui.control.RadioButton
        PMButton                    matlab.ui.control.RadioButton
        FlightPathCoordinatesLabel  matlab.ui.control.Label
        SetStartTimeLabel           matlab.ui.control.Label
        SetPathDivisionsLabel       matlab.ui.control.Label
        numPathDivisions            matlab.ui.control.Spinner
        flightPath                  matlab.ui.control.TextArea
        DrawPathButton              matlab.ui.control.Button
        speedAxes                   matlab.ui.control.UIAxes
        bearingAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        locationData
        gx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.locationData = load('C:\windApp\Update Locations\Locations.mat');
            S = shaperead('C:\windApp\Update Locations\tiles.shp');
            proj = projcrs(3857); %define the projection properties (EPSG Code = 3857 = Web Mercator)
            
            for i=1:length(S)
                xx = S(i).X;
                yy = S(i).Y;
                map(i).tileName = S(i).Tile_Name;
                [map(i).lat map(i).long] = projinv(proj,xx,yy);
            end
            
            app.gx=geoaxes(app.map);
            geobasemap(app.gx,'satellite')
            for i=1:length(map)
                if app.locationData.Locations(i).xSketchup
                    shape = polyshape(map(i).long,map(i).lat);
                    hold(app.gx,'on')
                    geoplot(app.gx,map(i).lat,map(i).long,'-w','LineWidth',2);
                end
            end
            app.gx.LatitudeLabel.String = '';
            app.gx.LongitudeLabel.String = '';
            
            app.flightPath.Value = ['43.65446663, -79.37821738, 20,' ' 43.65407185, -79.37996048, 20,' , ' 43.65908688, -79.382094, 20,', ' 43.65757628, -79.38940229, 20'];
            app.DateEditField.Value = char(datetime('now','format','yyyy-MM-dd'));;
            title(app.speedAxes, 'Wind Speeds (m/s)');
            xlabel(app.speedAxes, 'Relative Distance');
            ylabel(app.speedAxes,'Speed');
            title(app.bearingAxes,strcat('Wind Bearing', {' '}, '(', char(176), ')'));
            xlabel(app.bearingAxes, 'Relative Distance');
            ylabel(app.bearingAxes,'Bearing');
            yticks(app.bearingAxes, [0:60:360]);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            cla(app.speedAxes);
            cla(app.bearingAxes);
            date = app.DateEditField.Value;
            time = app.TimeSpinner.Value;
            isAM = app.AMButton.Value;
            numPathDivisions = app.numPathDivisions.Value;
            flightPath = cell2mat(app.flightPath.Value);
            
            [CFDMeanSpeeds,CFDPeakSpeeds,CFDMeanBearings,relativeDistances,latLongElev] = flightPathWindPrediction(flightPath,numPathDivisions,date,time,isAM,app.gx);
            
            plotFlightPoints(flightPath,numPathDivisions,app.gx)
            hold(app.speedAxes, 'on')
            plot(app.speedAxes, relativeDistances, CFDMeanSpeeds,':ok');
            plot(app.speedAxes, relativeDistances, CFDPeakSpeeds,':or');
            legend(app.speedAxes,{'Mean','Peak'},'Location','northwest')
            hold(app.speedAxes, 'off')
            plot(app.bearingAxes, relativeDistances, CFDMeanBearings,':ob');
            
%             lat = latLongElev(:,1);
%             long = latLongElev(:,2);
%             elevation = latLongElev(:,3);
%             meanSpeed = CFDMeanSpeeds;
%             peakSpeed = CFDPeakSpeeds;
%             bearing = CFDMeanBearings;
%             windSpeedBearingData = table(lat,long,elevation,meanSpeed,peakSpeed,bearing);
%             writetable(windSpeedBearingData);
            
        end

        % Value changed function: numPathDivisions
        function numPathDivisionsValueChanged(app, event)
            numPathDivisions = app.numPathDivisions.Value;
            flightPath = cell2mat(app.flightPath.Value);
            plotFlightPoints(flightPath,numPathDivisions,app.gx)
        end

        % Button pushed function: DrawPathButton
        function DrawPathButtonPushed(app, event)
            flightPath = cell2mat(app.flightPath.Value);
            plotFlightPoints(flightPath,1,app.gx)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DateEditFieldLabel = uilabel(app.UIFigure);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.UIFigure, 'text');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.map = uipanel(app.UIFigure);
            app.map.Title = 'Map';
            app.TimeSpinnerLabel = uilabel(app.UIFigure);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.UIFigure);
            app.TimeSpinner.Limits = [1 12];
            app.TimeSpinner.Value = 1;
            app.AMPMButtonGroup = uibuttongroup(app.UIFigure);
            app.AMPMButtonGroup.Title = 'AM/PM';
            app.AMButton = uiradiobutton(app.AMPMButtonGroup);
            app.AMButton.Text = 'AM';
            app.AMButton.Value = true;
            app.PMButton = uiradiobutton(app.AMPMButtonGroup);
            app.PMButton.Text = 'PM';
            app.FlightPathCoordinatesLabel = uilabel(app.UIFigure);
            app.FlightPathCoordinatesLabel.Text = 'Flight Path Coordinates';
            app.SetStartTimeLabel = uilabel(app.UIFigure);
            app.SetStartTimeLabel.Text = 'Set Start Time';
            app.SetPathDivisionsLabel = uilabel(app.UIFigure);
            app.SetPathDivisionsLabel.Text = 'Set Path Divisions';
            app.numPathDivisions = uispinner(app.UIFigure);
            app.numPathDivisions.Limits = [1 10];
            app.numPathDivisions.ValueChangedFcn = createCallbackFcn(app, @numPathDivisionsValueChanged, true);
            app.numPathDivisions.Value = 1;
            app.flightPath = uitextarea(app.UIFigure);
            app.DrawPathButton = uibutton(app.UIFigure, 'push');
            app.DrawPathButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPathButtonPushed, true);
            app.DrawPathButton.Text = 'Draw Path';
            app.speedAxes = uiaxes(app.UIFigure);
            title(app.speedAxes, 'Title')
            xlabel(app.speedAxes, 'X')
            ylabel(app.speedAxes, 'Y')
            zlabel(app.speedAxes, 'Z')
            app.bearingAxes = uiaxes(app.UIFigure);
            title(app.bearingAxes, 'Title')
            xlabel(app.bearingAxes, 'X')
            ylabel(app.bearingAxes, 'Y')
            zlabel(app.bearingAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end